classdef blqReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mapsPreviewUIFigure        matlab.ui.Figure
        CurrentAxes                matlab.ui.control.UIAxes
        ConductanceAxes            matlab.ui.control.UIAxes
        blqReaderLabel             matlab.ui.control.Label
        LoadblqButton              matlab.ui.control.Button
        ButtonGroup                matlab.ui.container.ButtonGroup
        NormalizedButton           matlab.ui.control.RadioButton
        NotnormalizedButton        matlab.ui.control.RadioButton
        fromEditFieldLabel         matlab.ui.control.Label
        fromEditField              matlab.ui.control.NumericEditField
        fromEditFieldLabel_2       matlab.ui.control.Label
        toEditField                matlab.ui.control.NumericEditField
        toEditFieldLabel           matlab.ui.control.Label
        fromEditFieldLabel_3       matlab.ui.control.Label
        CurvestoshowLabel          matlab.ui.control.Label
        CurvestoshowEditField      matlab.ui.control.NumericEditField
        DerivativepointsLabel      matlab.ui.control.Label
        DerivativepointsEditField  matlab.ui.control.NumericEditField
        OffsetvoltageLabel         matlab.ui.control.Label
        OffsetvoltageEditField     matlab.ui.control.NumericEditField
        CalculateButton            matlab.ui.control.Button
        fromEditFieldLabel_4       matlab.ui.control.Label
        ChangesamplecurvesButton   matlab.ui.control.Button
        AnalyzeimagesButton        matlab.ui.control.Button
        InitialpointSpinnerLabel   matlab.ui.control.Label
        InitialpointSpinner        matlab.ui.control.Spinner
    end

    
    properties (Access = public)
        CallingApp % main app object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
        end

        % Button pushed function: LoadblqButton
        function LoadblqButtonPushed(app, event)
            [app.CallingApp.Struct, app.CallingApp.MatrizCorriente, app.CallingApp.Voltaje] = loadblq(app, app.InitialpointSpinner.Value);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            [app.CallingApp.Struct] = calculateblq(app, app.CallingApp.Struct, app.CallingApp.Voltaje, app.CallingApp.MatrizCorriente);
        end

        % Button pushed function: ChangesamplecurvesButton
        function ChangesamplecurvesButtonPushed(app, event)
            [app.CallingApp.Struct] = changeCurves(app, app.CallingApp.Struct);
            CalculateButtonPushed(app)
        end

        % Button pushed function: AnalyzeimagesButton
        function AnalyzeimagesButtonPushed(app, event)
            [app.CallingApp.Struct] = analyzeImages(app.CallingApp.Struct);
            maps = num2str(length(app.CallingApp.Struct.Energia));
            uiwait(msgbox([maps ' maps succesfully generated'], 'You are almost there', 'help'))
            app.CallingApp.mapsPreviewApp = mapsPreview(app.CallingApp, app.CallingApp.Struct);
        end

        % Value changed function: InitialpointSpinner
        function InitialpointSpinnerValueChanged(app, event)
            value = app.InitialpointSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mapsPreviewUIFigure = uifigure('Visible', 'off');
            app.mapsPreviewUIFigure.Name = 'mapsPreview';
            app.CurrentAxes = uiaxes(app.mapsPreviewUIFigure);
            title(app.CurrentAxes, 'IV')
            xlabel(app.CurrentAxes, 'Bias Voltage (mV')
            ylabel(app.CurrentAxes, 'Tunneling current (nA)')
            app.ConductanceAxes = uiaxes(app.mapsPreviewUIFigure);
            title(app.ConductanceAxes, 'dI/dV')
            xlabel(app.ConductanceAxes, 'Bias Voiltage (mV)')
            ylabel(app.ConductanceAxes, 'Conductance (\muS)')
            app.blqReaderLabel = uilabel(app.mapsPreviewUIFigure);
            app.blqReaderLabel.Text = 'blqReader';
            app.LoadblqButton = uibutton(app.mapsPreviewUIFigure, 'push');
            app.LoadblqButton.ButtonPushedFcn = createCallbackFcn(app, @LoadblqButtonPushed, true);
            app.LoadblqButton.Text = 'Load blq';
            app.ButtonGroup = uibuttongroup(app.mapsPreviewUIFigure);
            app.NormalizedButton = uiradiobutton(app.ButtonGroup);
            app.NormalizedButton.Text = 'Normalized';
            app.NormalizedButton.Value = true;
            app.NotnormalizedButton = uiradiobutton(app.ButtonGroup);
            app.NotnormalizedButton.Text = 'Not normalized';
            app.fromEditFieldLabel = uilabel(app.mapsPreviewUIFigure);
            app.fromEditFieldLabel.Text = 'from';
            app.fromEditField = uieditfield(app.mapsPreviewUIFigure, 'numeric');
            app.fromEditFieldLabel_2 = uilabel(app.mapsPreviewUIFigure);
            app.fromEditFieldLabel_2.Text = 'mV';
            app.toEditField = uieditfield(app.mapsPreviewUIFigure, 'numeric');
            app.toEditFieldLabel = uilabel(app.mapsPreviewUIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.fromEditFieldLabel_3 = uilabel(app.mapsPreviewUIFigure);
            app.fromEditFieldLabel_3.Text = 'mV';
            app.CurvestoshowLabel = uilabel(app.mapsPreviewUIFigure);
            app.CurvestoshowLabel.Text = 'Curves to show:';
            app.CurvestoshowEditField = uieditfield(app.mapsPreviewUIFigure, 'numeric');
            app.CurvestoshowEditField.RoundFractionalValues = 'on';
            app.CurvestoshowEditField.Value = 1;
            app.DerivativepointsLabel = uilabel(app.mapsPreviewUIFigure);
            app.DerivativepointsLabel.Text = 'Derivative points:';
            app.DerivativepointsEditField = uieditfield(app.mapsPreviewUIFigure, 'numeric');
            app.DerivativepointsEditField.Value = 1;
            app.OffsetvoltageLabel = uilabel(app.mapsPreviewUIFigure);
            app.OffsetvoltageLabel.Text = 'Offset voltage:';
            app.OffsetvoltageEditField = uieditfield(app.mapsPreviewUIFigure, 'numeric');
            app.CalculateButton = uibutton(app.mapsPreviewUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.fromEditFieldLabel_4 = uilabel(app.mapsPreviewUIFigure);
            app.fromEditFieldLabel_4.Text = 'mV';
            app.ChangesamplecurvesButton = uibutton(app.mapsPreviewUIFigure, 'push');
            app.ChangesamplecurvesButton.ButtonPushedFcn = createCallbackFcn(app, @ChangesamplecurvesButtonPushed, true);
            app.ChangesamplecurvesButton.Text = 'Change sample curves';
            app.AnalyzeimagesButton = uibutton(app.mapsPreviewUIFigure, 'push');
            app.AnalyzeimagesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeimagesButtonPushed, true);
            app.AnalyzeimagesButton.Text = {'Analyze'; 'images'};
            app.InitialpointSpinnerLabel = uilabel(app.mapsPreviewUIFigure);
            app.InitialpointSpinnerLabel.Text = 'Initial point';
            app.InitialpointSpinner = uispinner(app.mapsPreviewUIFigure);
            app.InitialpointSpinner.Limits = [1 Inf];
            app.InitialpointSpinner.ValueChangedFcn = createCallbackFcn(app, @InitialpointSpinnerValueChanged, true);
            app.InitialpointSpinner.Value = 1;
            % Show the figure after all components are created
            app.mapsPreviewUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqReader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mapsPreviewUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mapsPreviewUIFigure)
        end
    end
end