classdef blqApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        blqAnalysisUIFigure           matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        InfoStructtoworkspaceMenu     matlab.ui.container.Menu
        SymmetrizeMenu                matlab.ui.container.Menu
        VerticalmirrorMenu            matlab.ui.container.Menu
        VerticalHorizontalmirrorMenu  matlab.ui.container.Menu
        C4symmetryMenu                matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        OpenTab                       matlab.ui.container.Tab
        NewButton                     matlab.ui.control.Button
        SaveInfostructButton          matlab.ui.control.Button
        LoadInfostructButton          matlab.ui.control.Button
        ClearallButton                matlab.ui.control.Button
        AnalizeRealTab                matlab.ui.container.Tab
        RealMapsButton                matlab.ui.control.Button
        ColormapRealDropDown          matlab.ui.control.DropDown
        KeepChangesButton             matlab.ui.control.Button
        VisualizeRealButton           matlab.ui.control.Button
        MeanConductanceButton         matlab.ui.control.Button
        RotateButton_2                matlab.ui.control.Button
        EditRotateAngle_2             matlab.ui.control.NumericEditField
        SaveallFFTButton_2            matlab.ui.control.Button
        GaussianFilterButton_2        matlab.ui.control.Button
        EditGaussianFilter_2          matlab.ui.control.NumericEditField
        AnalizeFFTTab                 matlab.ui.container.Tab
        FFTMapsButton                 matlab.ui.control.Button
        ColormapFFTDropDown           matlab.ui.control.DropDown
        RemoveCentralLineButton       matlab.ui.control.Button
        RotateButton                  matlab.ui.control.Button
        EditRotateAngle               matlab.ui.control.NumericEditField
        SymmetrizeButton              matlab.ui.control.Button
        AdjustZeroButton              matlab.ui.control.Button
        SaveallFFTButton              matlab.ui.control.Button
        GaussianFilterButton          matlab.ui.control.Button
        EditGaussianFilter            matlab.ui.control.NumericEditField
        AngleprofileButton            matlab.ui.control.Button
        EditProfileAngle              matlab.ui.control.NumericEditField
        RadialprofileButton           matlab.ui.control.Button
        VisualizeFFTButton            matlab.ui.control.Button
        KeepChangesButton_2           matlab.ui.control.Button
        VerticalprofileButton         matlab.ui.control.Button
        HorizontalprofileButton       matlab.ui.control.Button
        DiagonalButton                matlab.ui.control.Button
        blqAnalysisQPIStudyLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        Property % Description
        % Variables de la aplicación
        InfoStruct
        InfoStructAnalisis
        InfoStructAnalisisCopy
        
        fourierImage
        
        TransformadasAnalisis
        TransformadasAnalisisCopy
        
        Type
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            genpath('Functions');
            addpath(genpath('Functions'));
            
            app.Type = 3;
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            ImageAnalysisIV
        end

        % Button pushed function: SaveInfostructButton
        function SaveInfostructButtonPushed(app, event)
            saveInfo()
        end

        % Button pushed function: LoadInfostructButton
        function LoadInfostructButtonPushed(app, event)
            app.InfoStruct = openInfo();
            figure(app.blqAnalysisUIFigure)
            msgbox('InfoStruct succesfully loaded.','You are amazing','help')
%             app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
        end

        % Button pushed function: FFTMapsButton
        function FFTMapsButtonPushed(app, event)
            app.InfoStruct.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisis.Colormap = eval(app.ColormapFFTDropDown.Value);
            app.InfoStructAnalisisCopy.Colormap = eval(app.ColormapFFTDropDown.Value);
            VisualizeCell(app.InfoStruct.Transformadas,app.InfoStruct)
            app.fourierImage = gcf;
            %             app.fourierImage.UserData.Colormap = app.InfoStruct.Colormap;
            app.fourierImage.Colormap = eval(app.ColormapFFTDropDown.Value);
            
            app.InfoStruct = app.fourierImage.UserData;
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            
            %             app.TransformadasAnalisis = app.InfoStruct.Transformadas;
        end

        % Button pushed function: KeepChangesButton
        function KeepChangesButtonPushed(app, event)
            app.InfoStructAnalisis   = app.fourierImage.UserData;
            app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas;
            
            app.InfoStructAnalisisCopy.Contrast = app.fourierImage.UserData.Contrast;
            
            %             app.TransformadasAnalisis = app.TransformadasAnalisisCopy;
        end

        % Button pushed function: RemoveCentralLineButton
        function RemoveCentralLineButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RemoveCentralLine(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Value changed function: ColormapFFTDropDown
        function ColormapFFTDropDownValueChanged(app, event)
            value = app.ColormapFFTDropDown.Value;
            app.fourierImage.Colormap = eval(value);
            
            app.fourierImage.UserData.Colormap = eval(value);
            app.InfoStruct.Colormap = eval(value);
            app.InfoStructAnalisis.Colormap = eval(value);
            app.InfoStructAnalisisCopy.Colormap = eval(value);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = RotateMatrix(app.InfoStructAnalisis.Transformadas, app.EditRotateAngle.Value, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: SymmetrizeButton
        function SymmetrizeButtonPushed(app, event)
            app.InfoStructAnalisisCopy.Transformadas = simetrizarFFT_Automatico(app.InfoStructAnalisis.Transformadas, 0, app.Type);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: GaussianFilterButton
        function GaussianFilterButtonPushed(app, event)
            if app.EditGaussianFilter.Value > 0
                app.InfoStructAnalisisCopy.Transformadas = GaussSmooth(app.InfoStructAnalisis.Transformadas, app.EditGaussianFilter.Value, app.InfoStructAnalisis);
                VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
            end            
        end

        % Button pushed function: AdjustZeroButton
        function AdjustZeroButtonPushed(app, event)
            [app.InfoStructAnalisisCopy.Transformadas,  app.InfoStructAnalisisCopy] = adjustZero(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis);
            VisualizeCell(app.InfoStructAnalisisCopy.Transformadas, app.InfoStructAnalisisCopy);
        end

        % Button pushed function: SaveallFFTButton
        function SaveallFFTButtonPushed(app, event)
            saveCell(app.InfoStructAnalisis.Transformadas, app.InfoStructAnalisis)
            msgbox([num2str(length(app.InfoStruct.Energia)) ' png files succesfully saved.'],'Follow your dreams','help')
        end

        % Button pushed function: MeanConductanceButton
        function MeanConductanceButtonPushed(app, event)
            meanConductance(app.InfoStruct)
        end

        % Button pushed function: VerticalprofileButton
        function VerticalprofileButtonPushed(app, event)
            verticalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: HorizontalprofileButton
        function HorizontalprofileButtonPushed(app, event)
            horizontalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: DiagonalButton
        function DiagonalButtonPushed(app, event)
            diagonalProfile(app.InfoStructAnalisis)
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            clear variables
            evalin('base','clear variables')
        end

        % Button pushed function: KeepChangesButton_2
        function KeepChangesButton_2Pushed(app, event)
            app.InfoStructAnalisis = app.fourierImage.UserData;
            app.InfoStructAnalisis.Transformadas = app.InfoStructAnalisisCopy.Transformadas;
            app.InfoStructAnalisisCopy = app.InfoStructAnalisis;
            %             app.InfoStructAnalisisCopy.Contrast = app.fourierImage.UserData.Contrast;
            
            %             app.TransformadasAnalisis = app.TransformadasAnalisisCopy;
        end

        % Button pushed function: VisualizeFFTButton
        function VisualizeFFTButtonPushed(app, event)
            VisualizeCell(app.InfoStructAnalisis.Transformadas,app.InfoStructAnalisisCopy)
        end

        % Menu selected function: InfoStructtoworkspaceMenu
        function InfoStructtoworkspaceMenuSelected(app, event)
            assignin('base','Infostruct',app.InfoStruct)
            assignin('base','InfostructAnalisis',app.InfoStructAnalisis)
            assignin('base','InfostructAnalisisCopy',app.InfoStructAnalisisCopy)
%             assignin('base','fourierImage',app.fourierImage)
        end

        % Menu selected function: VerticalmirrorMenu
        function VerticalmirrorMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 1;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 0;
            
            app.Type = 2;
        end

        % Menu selected function: VerticalHorizontalmirrorMenu
        function VerticalHorizontalmirrorMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 1;
            app.C4symmetryMenu.Checked = 0;
            
            app.Type = 1;
        end

        % Menu selected function: C4symmetryMenu
        function C4symmetryMenuSelected(app, event)
            app.VerticalmirrorMenu.Checked = 0;
            app.VerticalHorizontalmirrorMenu.Checked = 0;
            app.C4symmetryMenu.Checked = 1;
            
            app.Type = 3;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.blqAnalysisUIFigure = uifigure('Visible', 'off');
            app.blqAnalysisUIFigure.Name = 'blqAnalysis';
            app.FileMenu = uimenu(app.blqAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.InfoStructtoworkspaceMenu = uimenu(app.FileMenu);
            app.InfoStructtoworkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @InfoStructtoworkspaceMenuSelected, true);
            app.InfoStructtoworkspaceMenu.Text = 'InfoStruct to workspace';
            app.SymmetrizeMenu = uimenu(app.blqAnalysisUIFigure);
            app.SymmetrizeMenu.Text = 'Symmetrize';
            app.VerticalmirrorMenu = uimenu(app.SymmetrizeMenu);
            app.VerticalmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalmirrorMenuSelected, true);
            app.VerticalmirrorMenu.Text = 'Vertical mirror';
            app.VerticalHorizontalmirrorMenu = uimenu(app.SymmetrizeMenu);
            app.VerticalHorizontalmirrorMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalHorizontalmirrorMenuSelected, true);
            app.VerticalHorizontalmirrorMenu.Text = 'Vertical + Horizontal mirror';
            app.C4symmetryMenu = uimenu(app.SymmetrizeMenu);
            app.C4symmetryMenu.MenuSelectedFcn = createCallbackFcn(app, @C4symmetryMenuSelected, true);
            app.C4symmetryMenu.Checked = 'on';
            app.C4symmetryMenu.Text = 'C4 symmetry';
            app.TabGroup = uitabgroup(app.blqAnalysisUIFigure);
            app.OpenTab = uitab(app.TabGroup);
            app.OpenTab.Title = 'Open';
            app.NewButton = uibutton(app.OpenTab, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Tooltip = {'Pincha aqúi'; ' subnormal'};
            app.NewButton.Text = 'New';
            app.SaveInfostructButton = uibutton(app.OpenTab, 'push');
            app.SaveInfostructButton.ButtonPushedFcn = createCallbackFcn(app, @SaveInfostructButtonPushed, true);
            app.SaveInfostructButton.Text = 'Save Infostruct';
            app.LoadInfostructButton = uibutton(app.OpenTab, 'push');
            app.LoadInfostructButton.ButtonPushedFcn = createCallbackFcn(app, @LoadInfostructButtonPushed, true);
            app.LoadInfostructButton.Text = 'Load Infostruct';
            app.ClearallButton = uibutton(app.OpenTab, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.AnalizeRealTab = uitab(app.TabGroup);
            app.AnalizeRealTab.Title = 'Analize Real';
            app.RealMapsButton = uibutton(app.AnalizeRealTab, 'push');
            app.RealMapsButton.Text = 'Real Maps';
            app.ColormapRealDropDown = uidropdown(app.AnalizeRealTab);
            app.ColormapRealDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'Spring', 'Summer', 'Autumn', 'Bone', 'Copper', 'Pink', 'Red', 'Green', 'Blue'};
            app.ColormapRealDropDown.Value = 'gray';
            app.KeepChangesButton = uibutton(app.AnalizeRealTab, 'push');
            app.KeepChangesButton.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButtonPushed, true);
            app.KeepChangesButton.Text = 'Keep Changes';
            app.VisualizeRealButton = uibutton(app.AnalizeRealTab, 'push');
            app.VisualizeRealButton.Text = 'Visualize Real';
            app.MeanConductanceButton = uibutton(app.AnalizeRealTab, 'push');
            app.MeanConductanceButton.ButtonPushedFcn = createCallbackFcn(app, @MeanConductanceButtonPushed, true);
            app.MeanConductanceButton.Text = 'Mean Conductance';
            app.RotateButton_2 = uibutton(app.AnalizeRealTab, 'push');
            app.RotateButton_2.Text = 'Rotate';
            app.EditRotateAngle_2 = uieditfield(app.AnalizeRealTab, 'numeric');
            app.SaveallFFTButton_2 = uibutton(app.AnalizeRealTab, 'push');
            app.SaveallFFTButton_2.Text = 'Save all FFT';
            app.GaussianFilterButton_2 = uibutton(app.AnalizeRealTab, 'push');
            app.GaussianFilterButton_2.Text = 'Gaussian Filter';
            app.EditGaussianFilter_2 = uieditfield(app.AnalizeRealTab, 'numeric');
            app.AnalizeFFTTab = uitab(app.TabGroup);
            app.AnalizeFFTTab.Title = 'Analize FFT';
            app.FFTMapsButton = uibutton(app.AnalizeFFTTab, 'push');
            app.FFTMapsButton.ButtonPushedFcn = createCallbackFcn(app, @FFTMapsButtonPushed, true);
            app.FFTMapsButton.Text = 'FFT Maps';
            app.ColormapFFTDropDown = uidropdown(app.AnalizeFFTTab);
            app.ColormapFFTDropDown.Items = {'gray', 'parula', 'jet', 'winter', 'hot', 'spring', 'summer', 'autumn', 'bone', 'copper', 'pink', 'red', 'green', 'blue'};
            app.ColormapFFTDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapFFTDropDownValueChanged, true);
            app.ColormapFFTDropDown.Value = 'gray';
            app.RemoveCentralLineButton = uibutton(app.AnalizeFFTTab, 'push');
            app.RemoveCentralLineButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveCentralLineButtonPushed, true);
            app.RemoveCentralLineButton.Text = 'Remove Central Line';
            app.RotateButton = uibutton(app.AnalizeFFTTab, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.EditRotateAngle = uieditfield(app.AnalizeFFTTab, 'numeric');
            app.SymmetrizeButton = uibutton(app.AnalizeFFTTab, 'push');
            app.SymmetrizeButton.ButtonPushedFcn = createCallbackFcn(app, @SymmetrizeButtonPushed, true);
            app.SymmetrizeButton.Text = 'Symmetrize';
            app.AdjustZeroButton = uibutton(app.AnalizeFFTTab, 'push');
            app.AdjustZeroButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustZeroButtonPushed, true);
            app.AdjustZeroButton.Text = 'Adjust (0,0)';
            app.SaveallFFTButton = uibutton(app.AnalizeFFTTab, 'push');
            app.SaveallFFTButton.ButtonPushedFcn = createCallbackFcn(app, @SaveallFFTButtonPushed, true);
            app.SaveallFFTButton.Text = 'Save all FFT';
            app.GaussianFilterButton = uibutton(app.AnalizeFFTTab, 'push');
            app.GaussianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianFilterButtonPushed, true);
            app.GaussianFilterButton.Text = 'Gaussian Filter';
            app.EditGaussianFilter = uieditfield(app.AnalizeFFTTab, 'numeric');
            app.AngleprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.AngleprofileButton.Text = 'Angle profile';
            app.EditProfileAngle = uieditfield(app.AnalizeFFTTab, 'numeric');
            app.RadialprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.RadialprofileButton.Text = 'Radial profile';
            app.VisualizeFFTButton = uibutton(app.AnalizeFFTTab, 'push');
            app.VisualizeFFTButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeFFTButtonPushed, true);
            app.VisualizeFFTButton.Text = 'Visualize FFT';
            app.KeepChangesButton_2 = uibutton(app.AnalizeFFTTab, 'push');
            app.KeepChangesButton_2.ButtonPushedFcn = createCallbackFcn(app, @KeepChangesButton_2Pushed, true);
            app.KeepChangesButton_2.Text = 'Keep Changes';
            app.VerticalprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.VerticalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalprofileButtonPushed, true);
            app.VerticalprofileButton.Text = 'Vertical profile';
            app.HorizontalprofileButton = uibutton(app.AnalizeFFTTab, 'push');
            app.HorizontalprofileButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalprofileButtonPushed, true);
            app.HorizontalprofileButton.Text = 'Horizontal profile';
            app.DiagonalButton = uibutton(app.AnalizeFFTTab, 'push');
            app.DiagonalButton.ButtonPushedFcn = createCallbackFcn(app, @DiagonalButtonPushed, true);
            app.DiagonalButton.Text = 'Diagonal';
            app.blqAnalysisQPIStudyLabel = uilabel(app.blqAnalysisUIFigure);
            app.blqAnalysisQPIStudyLabel.Text = 'blqAnalysis + QPI Study';
            % Show the figure after all components are created
            app.blqAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blqApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.blqAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.blqAnalysisUIFigure)
        end
    end
end